classdef Mori3_SurfaceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CentreheightSlider       matlab.ui.control.Slider
        HandinputButton          matlab.ui.control.StateButton
        CentreHeightValueLabel   matlab.ui.control.Label
        UpdaterobotButton        matlab.ui.control.Button
        NoofmodulesLabel         matlab.ui.control.Label
        NumModLabel              matlab.ui.control.Label
        ExternaledgelengthLabel  matlab.ui.control.Label
        ExtEdgeLengthValueLabel  matlab.ui.control.Label
        InternaledgelengthLabel  matlab.ui.control.Label
        IntEdgeLengthValueLabel  matlab.ui.control.Label
        DihedralangleLabel       matlab.ui.control.Label
        DihedralAngleValueLabel  matlab.ui.control.Label
        CentreheightLabel        matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Mori3VirtualreconfigurablesurfaceLabel  matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        CycleButton              matlab.ui.control.StateButton
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = public)
        hMinMax = zeros(4,2);
        maxEdgeLength = 192;
        minEdgeLength = 180;
        hMax = 161.4435;
        wFactor = 3.5;
        plotM;
        plotM2;
        plotH;
        plotS;
        fillM;
        x = -30:0.3:30;
        theta;
        s1 = serialport('COM3',115200);
        modules = zeros(3,3,6);
        modulesOffset = zeros(3,3,6);
        modulesOffsetV = zeros(3,12,6);
        first = 0;
        lastHeightOutput = 0;
    end
    
    properties (Access = private)
        % Connect to MQTT
        mqttHost=mqtt('tcp://192.168.1.2','Port',1883, 'ClientID','MAALAA');
        mySub;
    end
    
    methods (Access = private)
        
        function updateSurface(app, centreHeight)            
            % determine number of modules
            if (centreHeight >= app.hMinMax(4,1)) && (centreHeight <= app.hMinMax(4,2)+(app.hMinMax(3,1)-app.hMinMax(4,2))/2)
                numMod = 6;
                if centreHeight > app.hMinMax(4,2)
                    centreHeight = app.hMinMax(4,2);
                end
            elseif (centreHeight > app.hMinMax(4,2)+(app.hMinMax(3,1)-app.hMinMax(4,2))/2) && (centreHeight <= app.hMinMax(3,2)+(app.hMinMax(2,1)-app.hMinMax(3,2))/2)
                numMod = 5;
                if centreHeight < app.hMinMax(3,1)
                    centreHeight = app.hMinMax(3,1);
                elseif centreHeight > app.hMinMax(3,2)
                    centreHeight = app.hMinMax(3,2);
                end
            elseif (centreHeight > app.hMinMax(3,2)+(app.hMinMax(2,1)-app.hMinMax(3,2))/2) && (centreHeight <= app.hMinMax(2,2))
                numMod = 4;
                if centreHeight < app.hMinMax(2,1)
                    centreHeight = app.hMinMax(2,1);
                end
            elseif (centreHeight > app.hMinMax(2,2)) && (centreHeight <= app.hMinMax(1,2))
                numMod = 3;
            else
                %error
            end
            
            % compute solution
            extEdgeLength = app.minEdgeLength-0.01;
            intEdgeLength = 0;
            while (intEdgeLength > app.maxEdgeLength) ...
                    || (intEdgeLength < app.minEdgeLength)
                extEdgeLength = extEdgeLength + 0.01;
                baseIntEdgeLength = extEdgeLength/(2*sin(pi/numMod));
                intEdgeLength = sqrt(centreHeight^2 + baseIntEdgeLength^2);
            end
            
            % flat hexagon triangle height:
            triangleHeight = sqrt(baseIntEdgeLength^2-(extEdgeLength/2)^2);
            
            % angle between external edges
            extEdgeAngle=(2*pi+(numMod-4)*pi)/numMod;
            
            app.modules(:,:,1)=[-extEdgeLength/2,extEdgeLength/2,0;...
                -triangleHeight,-triangleHeight,0;...
                0,0,centreHeight];
            set(app.plotM(1),'XData',app.modules(1,:,1),'YData',app.modules(2,:,1),'ZData',app.modules(3,:,1));
            set(app.plotM2(1),'XData',app.modules(1,:,1)-7,'YData',app.modules(2,:,1)-7,'ZData',app.modules(3,:,1)+7);
            
            % loop to create and plot remaining module coordinates
            for m=2:6
                app.modules(:,:,m)=[app.modules(1,2,m-1),app.modules(1,2,m-1)+extEdgeLength*cos((pi-extEdgeAngle)*(m-1)),0;...
                    app.modules(2,2,m-1),app.modules(2,2,m-1)+extEdgeLength*sin((pi-extEdgeAngle)*(m-1)),0;...
                    0,0,centreHeight];
                set(app.plotM(m),'XData',app.modules(1,:,m),'YData',app.modules(2,:,m),'ZData',app.modules(3,:,m));
                set(app.plotM2(m),'XData',app.modules(1,:,m)-7,'YData',app.modules(2,:,m)-7,'ZData',app.modules(3,:,m)+7);
            end
            set(app.plotH,'XData',[0,0],'YData',[0,0],'ZData',[0,centreHeight]);
            
            % create offset module geometries
            for l = 1:6
                iter = 0; % from Mori3_ArmIK_07
                for vert = 1:3 
                    for vert2 = 1:3
                        if vert == vert2
                            continue
                        end
                        % create 6 points on edges 28.55mm from vertices
                        iter = iter+1;
                        normal = app.modules(:,vert,l)-app.modules(:,vert2,l);
                        normalN = normal/vecnorm(normal);
                        app.modulesOffsetV(:,iter,l) = app.modules(:,vert,l) - 28.55*normalN;
                        
                        % move 6 points outwards by 6mm orthogonal to edge
                        normal = mean(app.modules(:,[vert vert2],l),2)-mean(app.modules(:,:,l),2);
                        normalN = normal/vecnorm(normal);
                        app.modulesOffsetV(:,iter,l) = app.modulesOffsetV(:,iter,l) - 6*normalN;
                    end
                end
                normal = cross(app.modules(:,2,l)-app.modules(:,1,l),app.modules(:,3,l)-app.modules(:,1,l));
                normalN = normal/vecnorm(normal);
                for vert = 1:6
                    app.modulesOffsetV(:,vert,l) = app.modulesOffsetV(:,vert,l) + 6*normalN;
                end
                for vert = 7:12
                    app.modulesOffsetV(:,vert,l) = app.modulesOffsetV(:,vert-6,l) - 12*normalN;
                end

                if (app.first < 6)
                    k = convhull(app.modulesOffsetV(1,:,l),app.modulesOffsetV(2,:,l),app.modulesOffsetV(3,:,l));
                    app.fillM(l) = trimesh(k,app.modulesOffsetV(1,:,l)-7,app.modulesOffsetV(2,:,l)-7,app.modulesOffsetV(3,:,l)+7,[0.5 0.5 0.5],'Parent', app.UIAxes2);
                    set(app.fillM(l),'FaceColor',[0.5 0.5 0.5],'FaceAlpha',0.7,'EdgeColor','none');
                    app.first = app.first + 1;
                else
                    k = convhull(app.modulesOffsetV(1,:,l),app.modulesOffsetV(2,:,l),app.modulesOffsetV(3,:,l));
                    set(app.fillM(l),'faces',k);
                    set(app.fillM(l),'vertices',[app.modulesOffsetV(1,:,l)'-7 app.modulesOffsetV(2,:,l)'-7 app.modulesOffsetV(3,:,l)'+7]);
                end
            end
            
            
            %update virtual surface
            if centreHeight < 75.6635
                z = (centreHeight/75.6635).*app.hMax.*normpdf(app.x,0,app.wFactor*app.hMax/75.6635)./0.4;
            else
                z = app.hMax.*normpdf(app.x,0,app.wFactor*app.hMax/centreHeight)./0.4;
            end
            zz = app.wFactor*repmat(z',1,length(app.theta));
            set(app.plotS,'ZData',zz);
            
            % module 1 & 2 plane equation
            P1 = cross(app.modules(:,2,1)-app.modules(:,1,1),app.modules(:,3,1)-app.modules(:,2,1));
            P2 = cross(app.modules(:,2,2)-app.modules(:,1,2),app.modules(:,3,2)-app.modules(:,2,2));
            dihedral = acos(abs(P1(1)*P2(1) + P1(2)*P2(2) + P1(3)*P2(3)) / ...
                (sqrt(P1(1)^2 + P1(2)^2 + P1(3)^2) * sqrt(P2(1)^2 + P2(2)^2 + P2(3)^2)));
            dihedral = rad2deg(dihedral);
            
            app.NumModLabel.Text = sprintf('%d',numMod);
            app.ExtEdgeLengthValueLabel.Text = sprintf('%.1f mm',extEdgeLength);
            app.IntEdgeLengthValueLabel.Text = sprintf('%.1f mm',intEdgeLength);
            app.CentreHeightValueLabel.Text = sprintf('%.2f mm',centreHeight);
            app.DihedralAngleValueLabel.Text = sprintf('%.1f%c',dihedral,char(176));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            topic = 'Ext/g';
            message = 'hello';
            publish(app.mqttHost, topic, message);
            app.mySub=subscribe(app.mqttHost,'+/p');
            
            
            for i = 3:6
                % max
                baseIntEdgeLength = app.minEdgeLength/(2*sin(pi/i));
                app.hMinMax(i-2,2) = sqrt(app.maxEdgeLength^2 - baseIntEdgeLength^2);
                % min
                baseIntEdgeLength = app.maxEdgeLength/(2*sin(pi/i));
                app.hMinMax(i-2,1) = real(sqrt(app.minEdgeLength^2 - baseIntEdgeLength^2));
            end
            
            %% MAIN FIGURE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            view(app.UIAxes,-52,20)
            hold(app.UIAxes, 'on')
            set(app.UIAxes,'DataAspectRatio',[1 1 1])
            set(app.UIAxes,'LineWidth',1.5)
            xlabel(app.UIAxes,'x (mm)','FontSize',16);
            ylabel(app.UIAxes,'y (mm)','FontSize',16);
            zlabel(app.UIAxes,'z (mm)','FontSize',16);
            xlim(app.UIAxes,[-200 200]);
            ylim(app.UIAxes,[-180 180]);
            zlim(app.UIAxes,[-7 app.hMinMax(1,2)]);
            xticks(app.UIAxes,[-200,-100,0,100,200]);
            yticks(app.UIAxes,[-180,-90,0,90,180]);
            zticks(app.UIAxes,[0,50,100,150]);
             
            for a=-200:100:200
                plot3(app.UIAxes,[a a a],[-180 180 180],[-.7 -.7 app.hMinMax(1,2)],'Color',[0.85 0.85 0.85],'LineWidth',1.5);
            end
            for b=50:50:150
                plot3(app.UIAxes,[-200 200 200],[180 180 -180],[b b b],'Color',[0.85 0.85 0.85],'LineWidth',1.5);
            end
            for c=-180:90:180
                plot3(app.UIAxes,[-200 200 200],[c c c],[-.7 -.7 app.hMinMax(1,2)],'Color',[0.85 0.85 0.85],'LineWidth',1.5);
            end
            
            % plot edges
            for n=1:6
                app.plotM(n) = plot3(app.UIAxes,app.modules(1,:,n),app.modules(2,:,n),app.modules(3,:,n),'-.','Color',[0.5 0.5 0.5],'LineWidth',2);
            end
            
            centreHeight = 0;
            app.plotH = plot3(app.UIAxes,[0,0],[0,0],[0,centreHeight],':','Color',[0.2,0.5,0.2],'LineWidth',4);
                        
            % virtual surface
            app.theta = 0:(2*pi)/100:2*pi-(2*pi)/100;
            if centreHeight < 75.6635
                z = (centreHeight/75.6635).*app.hMax*normpdf(app.x,0,app.wFactor*app.hMax/75.6635)./0.4;
            else
                z = app.hMax*normpdf(app.x,0,app.wFactor*app.hMax/centreHeight)./0.4;
            end
            xx = 10.*bsxfun(@times,app.x',cos(app.theta));
            yy = 10.*bsxfun(@times,app.x',sin(app.theta));
            zz = app.wFactor*repmat(z',1,length(app.theta));
            
            app.plotS = surf(app.UIAxes,xx,yy,zz,'FaceAlpha',0.8,'EdgeColor','none');
            colormap(app.UIAxes,turbo)
            caxis(app.UIAxes,[-161.4/10 161.4/0.8]);
            
            %% SECONDARY FIGURE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            view(app.UIAxes2,-52,20)
            hold(app.UIAxes2, 'on')
            set(app.UIAxes2,'DataAspectRatio',[1 1 1])
            set(app.UIAxes2,'LineWidth',1)
            xlabel(app.UIAxes2,'x (mm)','FontSize',10);
            ylabel(app.UIAxes2,'y (mm)','FontSize',10);
            zlabel(app.UIAxes2,'z (mm)','FontSize',10);
            xlim(app.UIAxes2,[-200 200]);
            ylim(app.UIAxes2,[-180 180]);
            zlim(app.UIAxes2,[-7 app.hMinMax(1,2)]);
            xticks(app.UIAxes2,[-200,-100,0,100,200]);
            yticks(app.UIAxes2,[-180,-90,0,90,180]);
            zticks(app.UIAxes2,[0,50,100,150]);

            for a=-200:100:200
                plot3(app.UIAxes2,[a a a],[-180 180 180],[-.7 -.7 .7],'Color',[0.85 0.85 0.85],'LineWidth',1);
            end
            for c=-180:90:180
                plot3(app.UIAxes2,[-200 200 200],[c c c],[-.7 -.7 .7],'Color',[0.85 0.85 0.85],'LineWidth',1);
            end
            
            % plot edges
            for n=1:6
                app.plotM2(n) = plot3(app.UIAxes2,app.modules(1,:,n),app.modules(2,:,n),app.modules(3,:,n),'-.','Color',[0.25 0.25 0.25],'LineWidth',1);
            end
            
            updateSurface(app, centreHeight);
        end

        % Value changed function: CentreheightSlider
        function CentreheightSliderValueChanged(app, event)
            value = app.CentreheightSlider.Value;
            updateSurface(app,value);
        end

        % Value changing function: CentreheightSlider
        function CentreheightSliderValueChanging(app, event)
            changingValue = event.Value;
            updateSurface(app,changingValue);
        end

        % Value changed function: HandinputButton
        function HandinputButtonValueChanged(app, event)
            while(app.HandinputButton.Value == 1)
                tflvalue = read(app.s1, 1, 'uint8');
                if tflvalue > 193
                    tflvalue = 193;
                end
                tflvalue = (app.hMax-0.035)*tflvalue/193;
                app.CentreheightSlider.Value = tflvalue;
                updateSurface(app, tflvalue);
                spd = round(tflvalue/5);
                publish(app.mqttHost, 'Ext/g', sprintf('led %d %d %d', spd, spd, spd));    
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.CentreheightSlider.Value = 0;
            app.CycleButton.Value = 0;
            app.HandinputButton.Value = 0;
            updateSurface(app,0);
        end

        % Value changed function: CycleButton
        function CycleButtonValueChanged(app, event)
            while(app.CycleButton.Value == 1)
                for i = 0:2:floor(app.hMax)
                    if (app.CycleButton.Value ~= 1)
                        break
                    end
                    updateSurface(app,i);
                    app.CentreheightSlider.Value = i;
                    drawnow
                end
                for i = floor(app.hMax):-2:0
                    if (app.CycleButton.Value ~= 1)
                        break
                    end
                    updateSurface(app,i);
                    app.CentreheightSlider.Value = i;
                    drawnow
                end
            end
        end

        % Button pushed function: UpdaterobotButton
        function UpdaterobotButtonPushed(app, event)
            % send commands to robot - only implemented for 6 modules
            value = app.CentreheightSlider.Value;
            if value > 66.8132
                value = 66.8132;
            end
            if value <= 6
                spd = 47;
            elseif value <= 10.4
                spd = 17;
            elseif value <= 15.9
                spd = 11;
            elseif value <= 20.8
                spd = 8;
            elseif value <= 25.5
                spd = 6;
            elseif value <= 30.7
                spd = 5;
            elseif value <= 35.7
                spd = 4;
            elseif value <= 50.7
                spd = 3;
            else
                spd = 2;
            end
            
%             spd = round(spd*255/47);
            new = app.CentreheightSlider.Value;
            spd = round(new/5);
            publish(app.mqttHost, 'Ext/g', sprintf('led %d %d %d', spd, spd, spd));
%             publish(app.mqttHost, 'Ext/g', sprintf('spd %d',spd));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CentreheightSlider = uislider(app.UIFigure);
            app.CentreheightSlider.Limits = [0 161.44];
            app.CentreheightSlider.ValueChangedFcn = createCallbackFcn(app, @CentreheightSliderValueChanged, true);
            app.CentreheightSlider.ValueChangingFcn = createCallbackFcn(app, @CentreheightSliderValueChanging, true);
            app.HandinputButton = uibutton(app.UIFigure, 'state');
            app.HandinputButton.ValueChangedFcn = createCallbackFcn(app, @HandinputButtonValueChanged, true);
            app.HandinputButton.Text = 'Hand input';
            app.CentreHeightValueLabel = uilabel(app.UIFigure);
            app.CentreHeightValueLabel.Text = '0.00 mm';
            app.UpdaterobotButton = uibutton(app.UIFigure, 'push');
            app.UpdaterobotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterobotButtonPushed, true);
            app.UpdaterobotButton.Text = 'Update robot';
            app.NoofmodulesLabel = uilabel(app.UIFigure);
            app.NoofmodulesLabel.Text = 'No. of modules';
            app.NumModLabel = uilabel(app.UIFigure);
            app.NumModLabel.Text = '6';
            app.ExternaledgelengthLabel = uilabel(app.UIFigure);
            app.ExternaledgelengthLabel.Text = 'External edge length';
            app.ExtEdgeLengthValueLabel = uilabel(app.UIFigure);
            app.ExtEdgeLengthValueLabel.Text = '180 mm';
            app.InternaledgelengthLabel = uilabel(app.UIFigure);
            app.InternaledgelengthLabel.Text = 'Internal edge length';
            app.IntEdgeLengthValueLabel = uilabel(app.UIFigure);
            app.IntEdgeLengthValueLabel.Text = '180 mm';
            app.DihedralangleLabel = uilabel(app.UIFigure);
            app.DihedralangleLabel.Text = 'Dihedral angle';
            app.DihedralAngleValueLabel = uilabel(app.UIFigure);
            app.DihedralAngleValueLabel.Text = '0.0 °';
            app.CentreheightLabel = uilabel(app.UIFigure);
            app.CentreheightLabel.Text = 'Centre height';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'RRL_Logo_BlankDark_4_M.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Sign_Small_Dark.png';
            app.Mori3VirtualreconfigurablesurfaceLabel = uilabel(app.UIFigure);
            app.Mori3VirtualreconfigurablesurfaceLabel.Text = 'Mori3 - Virtual, reconfigurable surface';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CycleButton = uibutton(app.UIFigure, 'state');
            app.CycleButton.ValueChangedFcn = createCallbackFcn(app, @CycleButtonValueChanged, true);
            app.CycleButton.Text = 'Cycle';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mori3_SurfaceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end