classdef Six_module_interpolation_surface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CentreheightSlider       matlab.ui.control.Slider
        HandinputButton          matlab.ui.control.StateButton
        CentreHeightValueLabel   matlab.ui.control.Label
        UpdaterobotButton        matlab.ui.control.Button
        NoofmodulesLabel         matlab.ui.control.Label
        NumModLabel              matlab.ui.control.Label
        ExternaledgelengthLabel  matlab.ui.control.Label
        ExtEdgeLengthValueLabel  matlab.ui.control.Label
        InternaledgelengthLabel  matlab.ui.control.Label
        IntEdgeLengthValueLabel  matlab.ui.control.Label
        DihedralangleLabel       matlab.ui.control.Label
        DihedralAngleValueLabel  matlab.ui.control.Label
        CentreheightLabel        matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Mori3VirtualreconfigurablesurfaceLabel  matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        CycleButton              matlab.ui.control.StateButton
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = public)
        hMinMax = zeros(4,2);
        maxEdgeLength = 192;
        minEdgeLength = 180;
        hMax = 161.4435;
        wFactor = 3.5;
        plotM;
        plotM2;
        plotH;
        plotS;
        fillM;
        x = -30:0.3:30;
        theta;
        s1;% = serialport('COM3',115200);
        modules = zeros(3,3,6);
        modulesOffset = zeros(3,3,6);
        modulesOffsetV = zeros(3,12,6);
        first = 0;
        lastHeightOutput = 0;
        Timer;
%         TimerUpdateRate = 0.5;     % In seconds
        TimerUpdateRate = 0.1;     % In seconds
        SendUpdatedValue = false;
        moduleStable = false;
        moduleAngle = 0;
        commandId = 1;
        desiredCommandId = 1;
        hexagon_steps;
        handCount = 0;
        publishDelay = 10; % FR
    end
    
    properties (Access = private)
        % Connect to MQTT
        mqttHost=mqtt('tcp://192.168.1.2','Port',1883, 'ClientID','MAALAA');
        mqttSubAngle;
        mqttSubStable;
    end
    
    methods (Access = private)
        
        function updateSurface(app, centreHeight)            
            % determine number of modules
            if (centreHeight >= app.hMinMax(4,1)) && (centreHeight <= app.hMinMax(4,2)+(app.hMinMax(3,1)-app.hMinMax(4,2))/2)
                numMod = 6;
                if centreHeight > app.hMinMax(4,2)
                    centreHeight = app.hMinMax(4,2);
                end
            elseif (centreHeight > app.hMinMax(4,2)+(app.hMinMax(3,1)-app.hMinMax(4,2))/2) && (centreHeight <= app.hMinMax(3,2)+(app.hMinMax(2,1)-app.hMinMax(3,2))/2)
                numMod = 5;
                if centreHeight < app.hMinMax(3,1)
                    centreHeight = app.hMinMax(3,1);
                elseif centreHeight > app.hMinMax(3,2)
                    centreHeight = app.hMinMax(3,2);
                end
            elseif (centreHeight > app.hMinMax(3,2)+(app.hMinMax(2,1)-app.hMinMax(3,2))/2) && (centreHeight <= app.hMinMax(2,2))
                numMod = 4;
                if centreHeight < app.hMinMax(2,1)
                    centreHeight = app.hMinMax(2,1);
                end
            elseif (centreHeight > app.hMinMax(2,2)) && (centreHeight <= app.hMinMax(1,2))
                numMod = 3;
            else
                %error
            end
            
            % compute solution
            extEdgeLength = app.minEdgeLength-0.01;
            intEdgeLength = 0;
            while (intEdgeLength > app.maxEdgeLength) ...
                    || (intEdgeLength < app.minEdgeLength)
                extEdgeLength = extEdgeLength + 0.01;
                baseIntEdgeLength = extEdgeLength/(2*sin(pi/numMod));
                intEdgeLength = sqrt(centreHeight^2 + baseIntEdgeLength^2);
            end
            
            % flat hexagon triangle height:
            triangleHeight = sqrt(baseIntEdgeLength^2-(extEdgeLength/2)^2);
            
            % angle between external edges
            extEdgeAngle=(2*pi+(numMod-4)*pi)/numMod;
            
            app.modules(:,:,1)=[-extEdgeLength/2,extEdgeLength/2,0;...
                -triangleHeight,-triangleHeight,0;...
                0,0,centreHeight];
            set(app.plotM(1),'XData',app.modules(1,:,1),'YData',app.modules(2,:,1),'ZData',app.modules(3,:,1));
            set(app.plotM2(1),'XData',app.modules(1,:,1)-7,'YData',app.modules(2,:,1)-7,'ZData',app.modules(3,:,1)+7);
            
            % loop to create and plot remaining module coordinates
            for m=2:6
                app.modules(:,:,m)=[app.modules(1,2,m-1),app.modules(1,2,m-1)+extEdgeLength*cos((pi-extEdgeAngle)*(m-1)),0;...
                    app.modules(2,2,m-1),app.modules(2,2,m-1)+extEdgeLength*sin((pi-extEdgeAngle)*(m-1)),0;...
                    0,0,centreHeight];
                set(app.plotM(m),'XData',app.modules(1,:,m),'YData',app.modules(2,:,m),'ZData',app.modules(3,:,m));
                set(app.plotM2(m),'XData',app.modules(1,:,m)-7,'YData',app.modules(2,:,m)-7,'ZData',app.modules(3,:,m)+7);
            end
            set(app.plotH,'XData',[0,0],'YData',[0,0],'ZData',[0,centreHeight]);
            
            % create offset module geometries
            for l = 1:6
                iter = 0; % from Mori3_ArmIK_07
                for vert = 1:3 
                    for vert2 = 1:3
                        if vert == vert2
                            continue
                        end
                        % create 6 points on edges 28.55mm from vertices
                        iter = iter+1;
                        normal = app.modules(:,vert,l)-app.modules(:,vert2,l);
                        normalN = normal/vecnorm(normal);
                        app.modulesOffsetV(:,iter,l) = app.modules(:,vert,l) - 28.55*normalN;
                        
                        % move 6 points outwards by 6mm orthogonal to edge
                        normal = mean(app.modules(:,[vert vert2],l),2)-mean(app.modules(:,:,l),2);
                        normalN = normal/vecnorm(normal);
                        app.modulesOffsetV(:,iter,l) = app.modulesOffsetV(:,iter,l) - 6*normalN;
                    end
                end
                normal = cross(app.modules(:,2,l)-app.modules(:,1,l),app.modules(:,3,l)-app.modules(:,1,l));
                normalN = normal/vecnorm(normal);
                for vert = 1:6
                    app.modulesOffsetV(:,vert,l) = app.modulesOffsetV(:,vert,l) + 6*normalN;
                end
                for vert = 7:12
                    app.modulesOffsetV(:,vert,l) = app.modulesOffsetV(:,vert-6,l) - 12*normalN;
                end

                if (app.first < 6)
                    k = convhull(app.modulesOffsetV(1,:,l),app.modulesOffsetV(2,:,l),app.modulesOffsetV(3,:,l));
                    app.fillM(l) = trimesh(k,app.modulesOffsetV(1,:,l)-7,app.modulesOffsetV(2,:,l)-7,app.modulesOffsetV(3,:,l)+7,[0.5 0.5 0.5],'Parent', app.UIAxes2);
                    set(app.fillM(l),'FaceColor',[0.5 0.5 0.5],'FaceAlpha',0.7,'EdgeColor','none');
                    app.first = app.first + 1;
                else
                    k = convhull(app.modulesOffsetV(1,:,l),app.modulesOffsetV(2,:,l),app.modulesOffsetV(3,:,l));
                    set(app.fillM(l),'faces',k);
                    set(app.fillM(l),'vertices',[app.modulesOffsetV(1,:,l)'-7 app.modulesOffsetV(2,:,l)'-7 app.modulesOffsetV(3,:,l)'+7]);
                end
            end
            
            
            %update virtual surface
            if centreHeight < 75.6635
                z = (centreHeight/75.6635).*app.hMax.*normpdf(app.x,0,app.wFactor*app.hMax/75.6635)./0.4;
            else
                z = app.hMax.*normpdf(app.x,0,app.wFactor*app.hMax/centreHeight)./0.4;
            end
            zz = app.wFactor*repmat(z',1,length(app.theta));
            set(app.plotS,'ZData',zz);
            
            % module 1 & 2 plane equation
            dihedral = app.calculateDihedral();
            
            app.NumModLabel.Text = sprintf('%d',numMod);
            app.ExtEdgeLengthValueLabel.Text = sprintf('%.1f mm',extEdgeLength);
            app.IntEdgeLengthValueLabel.Text = sprintf('%.1f mm',intEdgeLength);
            app.CentreHeightValueLabel.Text = sprintf('%.2f mm',centreHeight);
            app.DihedralAngleValueLabel.Text = sprintf('%.1f%c',dihedral,char(176));
        end
        
        
        function mainTimerCallback(app, event)
            app.mqttUpdateCallback()
            app.checkRobotAngles()
            app.updateHandInput()
        end
        
        function checkRobotAngles(app)
            app.publishDelay = app.publishDelay+1;
            if app.commandId == app.desiredCommandId
                return
            end
            if app.moduleStable == false
                return
            end
            
            if app.publishDelay < 10
                return
            end
            
            
            app.commandId = app.calculateClosestStepId(app.moduleAngle);
            disp(sprintf("commandId: %d", app.commandId));
            disp(sprintf("desiredId: %d", app.commandId));
            % If command smaller, climb to desired
            if app.commandId < app.desiredCommandId
                app.sendRobotAngles(app.commandId+1, false)
            elseif app.commandId == app.desiredCommandId
                app.sendRobotAngles(app.commandId, false)
            else
                app.sendRobotAngles(app.commandId-1, true)
            end
            app.moduleStable = false;
            app.publishDelay = 0;
        end
        
        function sendRobotAngles(app, hex_id, step_down)
%             hex_id = hex_id + 1;
            disp(app.hexagon_steps(hex_id).label);
            speed = app.hexagon_steps(hex_id).speed;
            cmd = app.hexagon_steps(hex_id).cmdId;
            angle = app.hexagon_steps(hex_id).ang;
            exten = app.hexagon_steps(hex_id).ext;
            
            if (step_down == true) && (hex_id > 1)
                speed = app.hexagon_steps(hex_id-1).speed;
            end
            
            speedMessage = sprintf("spd %d %d %d", speed, speed, speed);
            angleMessage = sprintf("ang - %d %d /%d", angle, angle, cmd);
            extenMessage = sprintf("ext - %d %d /%d", exten, exten, cmd);
            disp(speedMessage);
            disp(angleMessage);
            disp(extenMessage);    
            publish(app.mqttHost, 'Ext/g', speedMessage);    
            publish(app.mqttHost, 'Ext/g', angleMessage);    
            publish(app.mqttHost, 'Ext/g', extenMessage);    
        end
        
        function mqttUpdateCallback(app)
            StableMessage=read(app.mqttSubStable);
            app.moduleStable = (StableMessage == "True");
            AngleMessage=read(app.mqttSubAngle);
            app.moduleAngle = str2double(AngleMessage);
            app.updateLeds()
        end
        
        function updateLeds(app)
            cmap = turbo;
            act_ang = app.moduleAngle;
            lower_step = cast(app.lowerStepId(act_ang), "uint8");
            heigher_step = lower_step + 1;
            
            x_h = app.hexagon_steps(lower_step).height;
            x_a = app.hexagon_steps(lower_step).ang;
            y_h = app.hexagon_steps(heigher_step).height;
            y_a = app.hexagon_steps(heigher_step).ang;
                      
            mod_height = x_h + (act_ang-x_a)*((y_h-x_h)/(y_a-x_a));
            mod_height = mod_height / 10;
            
%             [-161.4/10 161.4/0.8]
%             x = 161.4/10 161.4/0.8
%             led_val = (mod_height) * ((161.4/0.8) + 164.4/10) ./ (161.4) + 164.4/10;
            led_val = (mod_height + 164.4/10) * ((255) / (161.4/0.8));
%             output = (value - fromLow) .* (toHigh - toLow) ./ (fromHigh - fromLow) + toLow;
%             led_val = mod_height + 164.4/10
            ledColours = ind2rgb(cast(led_val, 'uint8'), cmap);
            ledColours = cast((ledColours.^2) * 30, 'uint8');
            
            ledMessage = sprintf("led %d %d %d", ledColours(1), ledColours(2), ledColours(3));
%             disp(ledMessage);
            publish(app.mqttHost, 'Ext/g', ledMessage);
        end
        
        
        function updateHandInput(app)
            if(app.HandinputButton.Value == 1)
                if app.s1.NumBytesAvailable == 0
                    return
                end
                tflvalue = read(app.s1, 1, 'uint8');
                if tflvalue > 193
                    tflvalue = 193;
                end
                tflvalue = (app.hMax-0.035)*tflvalue/193;
                tflvalue = tflvalue * 0.44;
                app.CentreheightSlider.Value = tflvalue;
                updateSurface(app, tflvalue);
%                 spd = round(tflvalue/5);
                flush(app.s1)
            end
        end
        
        function [dihedral] = calculateDihedral(app)
            P1 = cross(app.modules(:,2,1)-app.modules(:,1,1),app.modules(:,3,1)-app.modules(:,2,1));
            P2 = cross(app.modules(:,2,2)-app.modules(:,1,2),app.modules(:,3,2)-app.modules(:,2,2));
            dihedral = acos(abs(P1(1)*P2(1) + P1(2)*P2(2) + P1(3)*P2(3)) / ...
                (sqrt(P1(1)^2 + P1(2)^2 + P1(3)^2) * sqrt(P2(1)^2 + P2(2)^2 + P2(3)^2)));
            dihedral = rad2deg(dihedral);
        end
        
        function [closest_step] = calculateClosestStepId(app, angle)
            smallest_range = inf;
            closest_step = -1;
            for i = 1:length(app.hexagon_steps)
                tmp_closest = abs(angle-app.hexagon_steps(i).ang);
                if tmp_closest < smallest_range
                    closest_step = i;
                    smallest_range = tmp_closest;
                end
            end
            disp(closest_step)
        end
        
        function [closest_step] = lowerStepId(app, angle)
            closest_step = -1;
            for i = 1:length(app.hexagon_steps)
                if angle-app.hexagon_steps(i).ang < 0
                    closest_step = i-1;
                    if closest_step <= 0
                        closest_step = 1;
                    end
                    return
                end
            end
            if closest_step == -1
                closest_step = 13;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp("Running");            
            % Connects to MQTT
            topic = 'Ext/g';
            message = 'hello';
            publish(app.mqttHost, topic, message);
            app.mqttSubAngle=subscribe(app.mqttHost,'matlab/angle');
            app.mqttSubStable=subscribe(app.mqttHost,'matlab/stable');
            
            publish(app.mqttHost, 'Ext/g', "dlf 1");    
            publish(app.mqttHost, 'Ext/g', "dlp a 250");            
            
            % Detects and connects to Com port (Windows only)
            [err,str] = system('REG QUERY HKEY_LOCAL_MACHINE\HARDWARE\DEVICEMAP\SERIALCOMM');
            if err
                ports = [];
            else
                ports = regexp(str,'\\Device\\(?<type>[^ ]*) *REG_SZ *(?<port>COM.*?)\n','names');
                cmd = 'REG QUERY HKEY_LOCAL_MACHINE\SYSTEM\CurrentControlSet\Enum\ /s /f "FriendlyName" /t "REG_SZ"';
                if exist('jsystem','file')==2 %~10x faster then 'system'
                    [~,str] = jsystem(cmd,'noshell');
                else
                    [~,str] = system(cmd);
                end
                names = regexp(str,'FriendlyName *REG_SZ *(?<name>.*?) \((?<port>COM.*?)\)','names');
                [i,j] = ismember({ports.port},{names.port});
                [ports(i).name] = names(j(i)).name;
            end
            
            usbName = "";
            for i = 1:length(ports)
                if contains(ports(i).name,'Arduino Leonardo')
                    usbName = ports(i).port;
                    break
                end
            end
            
            app.s1 = serialport(usbName,115200);
            
            read(app.s1, 1, 'uint8');
            
            % Creates timer to update MQTT messages
            app.Timer = timer;                                 % Create timer
            app.Timer.ExecutionMode = 'fixedRate' ;
            app.Timer.Period = app.TimerUpdateRate ;
            app.Timer.TimerFcn = @(~,~)app.mainTimerCallback ;
            start(app.Timer) ;       
            
            app.hexagon_steps =evalin('base','hexagon_steps');
            disp(app.hexagon_steps)
            
            for i = 3:6
                % max
                baseIntEdgeLength = app.minEdgeLength/(2*sin(pi/i));
                app.hMinMax(i-2,2) = sqrt(app.maxEdgeLength^2 - baseIntEdgeLength^2);
                % min
                baseIntEdgeLength = app.maxEdgeLength/(2*sin(pi/i));
                app.hMinMax(i-2,1) = real(sqrt(app.minEdgeLength^2 - baseIntEdgeLength^2));
            end
            
            %% MAIN FIGURE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            view(app.UIAxes,-52,20)
            hold(app.UIAxes, 'on')
            set(app.UIAxes,'DataAspectRatio',[1 1 1])
            set(app.UIAxes,'LineWidth',1.5)
            xlabel(app.UIAxes,'x (mm)','FontSize',16);
            ylabel(app.UIAxes,'y (mm)','FontSize',16);
            zlabel(app.UIAxes,'z (mm)','FontSize',16);
            xlim(app.UIAxes,[-200 200]);
            ylim(app.UIAxes,[-180 180]);
            zlim(app.UIAxes,[-7 app.hMinMax(1,2)]);
            xticks(app.UIAxes,[-200,-100,0,100,200]);
            yticks(app.UIAxes,[-180,-90,0,90,180]);
            zticks(app.UIAxes,[0,50,100,150]);
             
            for a=-200:100:200
                plot3(app.UIAxes,[a a a],[-180 180 180],[-.7 -.7 app.hMinMax(1,2)],'Color',[0.85 0.85 0.85],'LineWidth',1.5);
            end
            for b=50:50:150
                plot3(app.UIAxes,[-200 200 200],[180 180 -180],[b b b],'Color',[0.85 0.85 0.85],'LineWidth',1.5);
            end
            for c=-180:90:180
                plot3(app.UIAxes,[-200 200 200],[c c c],[-.7 -.7 app.hMinMax(1,2)],'Color',[0.85 0.85 0.85],'LineWidth',1.5);
            end
            
            % plot edges
            for n=1:6
                app.plotM(n) = plot3(app.UIAxes,app.modules(1,:,n),app.modules(2,:,n),app.modules(3,:,n),'-.','Color',[0.5 0.5 0.5],'LineWidth',2);
            end
            
            centreHeight = 0;
            app.plotH = plot3(app.UIAxes,[0,0],[0,0],[0,centreHeight],':','Color',[0.2,0.5,0.2],'LineWidth',4);
                        
            % virtual surface
            app.theta = 0:(2*pi)/100:2*pi-(2*pi)/100;
            if centreHeight < 75.6635
                z = (centreHeight/75.6635).*app.hMax*normpdf(app.x,0,app.wFactor*app.hMax/75.6635)./0.4;
            else
                z = app.hMax*normpdf(app.x,0,app.wFactor*app.hMax/centreHeight)./0.4;
            end
            xx = 10.*bsxfun(@times,app.x',cos(app.theta));
            yy = 10.*bsxfun(@times,app.x',sin(app.theta));
            zz = app.wFactor*repmat(z',1,length(app.theta));
            
            app.plotS = surf(app.UIAxes,xx,yy,zz,'FaceAlpha',0.8,'EdgeColor','none');
            colormap(app.UIAxes,turbo)
            caxis(app.UIAxes,[-161.4/10 161.4/0.8]);
            
            %% SECONDARY FIGURE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            view(app.UIAxes2,-52,20)
            hold(app.UIAxes2, 'on')
            set(app.UIAxes2,'DataAspectRatio',[1 1 1])
            set(app.UIAxes2,'LineWidth',1)
            xlabel(app.UIAxes2,'x (mm)','FontSize',10);
            ylabel(app.UIAxes2,'y (mm)','FontSize',10);
            zlabel(app.UIAxes2,'z (mm)','FontSize',10);
            xlim(app.UIAxes2,[-200 200]);
            ylim(app.UIAxes2,[-180 180]);
            zlim(app.UIAxes2,[-7 app.hMinMax(1,2)]);
            xticks(app.UIAxes2,[-200,-100,0,100,200]);
            yticks(app.UIAxes2,[-180,-90,0,90,180]);
            zticks(app.UIAxes2,[0,50,100,150]);

            for a=-200:100:200
                plot3(app.UIAxes2,[a a a],[-180 180 180],[-.7 -.7 .7],'Color',[0.85 0.85 0.85],'LineWidth',1);
            end
            for c=-180:90:180
                plot3(app.UIAxes2,[-200 200 200],[c c c],[-.7 -.7 .7],'Color',[0.85 0.85 0.85],'LineWidth',1);
            end
            
            % plot edges
            for n=1:6
                app.plotM2(n) = plot3(app.UIAxes2,app.modules(1,:,n),app.modules(2,:,n),app.modules(3,:,n),'-.','Color',[0.25 0.25 0.25],'LineWidth',1);
            end
            
            updateSurface(app, centreHeight);
        end

        % Value changed function: CentreheightSlider
        function CentreheightSliderValueChanged(app, event)
            value = app.CentreheightSlider.Value;
            updateSurface(app,value);
        end

        % Value changing function: CentreheightSlider
        function CentreheightSliderValueChanging(app, event)
            changingValue = event.Value;
            updateSurface(app,changingValue);
        end

        % Value changed function: HandinputButton
        function HandinputButtonValueChanged(app, event)
            flush(app.s1)
            return
            while(app.HandinputButton.Value == 1)
                app.handCount = app.handCount +1;
                if app.handCount < 100
                    continue
                end
                app.handCount = 0;
                tflvalue = read(app.s1, 1, 'uint8');
                if tflvalue > 193
                    tflvalue = 193;
                end
                tflvalue = (app.hMax-0.035)*tflvalue/193;
                tflvalue = tflvalue * 0.44;
                app.CentreheightSlider.Value = tflvalue;
                updateSurface(app, tflvalue);
                spd = round(tflvalue/5);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.CentreheightSlider.Value = 0;
            app.CycleButton.Value = 0;
            app.HandinputButton.Value = 0;
            updateSurface(app,0);
        end

        % Value changed function: CycleButton
        function CycleButtonValueChanged(app, event)
            while(app.CycleButton.Value == 1)
                for i = 0:2:floor(app.hMax)
                    if (app.CycleButton.Value ~= 1)
                        break
                    end
                    updateSurface(app,i);
                    app.CentreheightSlider.Value = i;
                    drawnow
                end
                for i = floor(app.hMax):-2:0
                    if (app.CycleButton.Value ~= 1)
                        break
                    end
                    updateSurface(app,i);
                    app.CentreheightSlider.Value = i;
                    drawnow
                end
            end
        end

        % Button pushed function: UpdaterobotButton
        function UpdaterobotButtonPushed(app, event)
            % send commands to robot - only implemented for 6 modules
%             value = app.CentreheightSlider.Value;
            app.HandinputButton.Value = 0;
            dihedral = app.calculateDihedral();
            module_angle = dihedral*10+1800;
%             disp(dihedral)
%             disp(module_angle)
            closest_step = app.calculateClosestStepId(module_angle);
            disp("goal_step");
            disp(closest_step);

            if closest_step < 1
                disp("ERROR: Step size below one");
                return
            end
            
            app.desiredCommandId = closest_step;
            return
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.Timer);
            delete(app)
            disp("Stopped App")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CentreheightSlider = uislider(app.UIFigure);
            app.CentreheightSlider.Limits = [0 161.44];
            app.CentreheightSlider.ValueChangedFcn = createCallbackFcn(app, @CentreheightSliderValueChanged, true);
            app.CentreheightSlider.ValueChangingFcn = createCallbackFcn(app, @CentreheightSliderValueChanging, true);
            app.HandinputButton = uibutton(app.UIFigure, 'state');
            app.HandinputButton.ValueChangedFcn = createCallbackFcn(app, @HandinputButtonValueChanged, true);
            app.HandinputButton.Text = 'Hand input';
            app.CentreHeightValueLabel = uilabel(app.UIFigure);
            app.CentreHeightValueLabel.Text = '0.00 mm';
            app.UpdaterobotButton = uibutton(app.UIFigure, 'push');
            app.UpdaterobotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterobotButtonPushed, true);
            app.UpdaterobotButton.Text = 'Update robot';
            app.NoofmodulesLabel = uilabel(app.UIFigure);
            app.NoofmodulesLabel.Text = 'No. of modules';
            app.NumModLabel = uilabel(app.UIFigure);
            app.NumModLabel.Text = '6';
            app.ExternaledgelengthLabel = uilabel(app.UIFigure);
            app.ExternaledgelengthLabel.Text = 'External edge length';
            app.ExtEdgeLengthValueLabel = uilabel(app.UIFigure);
            app.ExtEdgeLengthValueLabel.Text = '180 mm';
            app.InternaledgelengthLabel = uilabel(app.UIFigure);
            app.InternaledgelengthLabel.Text = 'Internal edge length';
            app.IntEdgeLengthValueLabel = uilabel(app.UIFigure);
            app.IntEdgeLengthValueLabel.Text = '180 mm';
            app.DihedralangleLabel = uilabel(app.UIFigure);
            app.DihedralangleLabel.Text = 'Dihedral angle';
            app.DihedralAngleValueLabel = uilabel(app.UIFigure);
            app.DihedralAngleValueLabel.Text = '0.0 °';
            app.CentreheightLabel = uilabel(app.UIFigure);
            app.CentreheightLabel.Text = 'Centre height';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'RRL_Logo_BlankDark_4_M.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Sign_Small_Dark.png';
            app.Mori3VirtualreconfigurablesurfaceLabel = uilabel(app.UIFigure);
            app.Mori3VirtualreconfigurablesurfaceLabel.Text = 'Mori3 - Virtual, reconfigurable surface';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CycleButton = uibutton(app.UIFigure, 'state');
            app.CycleButton.ValueChangedFcn = createCallbackFcn(app, @CycleButtonValueChanged, true);
            app.CycleButton.Text = 'Cycle';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Six_module_interpolation_surface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end